classdef acara17_tes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        SimpanGambarButton  matlab.ui.control.Button
        EditField           matlab.ui.control.EditField
        EditFieldLabel      matlab.ui.control.Label
        ThresholdButton     matlab.ui.control.Button
        GrayscaleButton     matlab.ui.control.Button
        BukaGambarButton    matlab.ui.control.Button
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BukaGambarButton
        function BukaGambarButtonPushed(app, event)
            [name_file1,name_path1] = uigetfile( ...
                {'*.bmp;*.jpg;*.tif','Files of type (*.bmp,*.jpg,*.tif)';
                '*.bmp','File Bitmap (*.bmp)';...
                '*.jpg','File jpeg (*.jpg)';
                '*.tif','File Tif (*.tif)';
                '*.*','All Files (*.*)'},...
                'Open Image');
            if ~isequal(name_file1,0)
                event.data1 = imread(fullfile(name_path1,name_file1));
                guidata(event,app);
                axes(app.UIAxes);
                imshow(event.data1);
            else
                return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Before')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'After')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.BukaGambarButton = uibutton(app.UIFigure, 'push');
            app.BukaGambarButton.ButtonPushedFcn = createCallbackFcn(app, @BukaGambarButtonPushed, true);
            app.BukaGambarButton.Text = 'Buka Gambar';
            app.GrayscaleButton = uibutton(app.UIFigure, 'push');
            app.GrayscaleButton.Text = 'Grayscale';
            app.ThresholdButton = uibutton(app.UIFigure, 'push');
            app.ThresholdButton.Text = 'Threshold';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.SimpanGambarButton = uibutton(app.UIFigure, 'push');
            app.SimpanGambarButton.Text = 'Simpan Gambar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = acara17_tes

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end